classdef GUI_416 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AmplitudeuAcm2Label          matlab.ui.control.Label
        AmplitudeuAcm2EditField      matlab.ui.control.EditField
        DurationmsecEditFieldLabel   matlab.ui.control.Label
        DurationmsecEditField        matlab.ui.control.EditField
        MorethanonestimulusCheckBox  matlab.ui.control.CheckBox
        TimedelaymsecEditFieldLabel  matlab.ui.control.Label
        TimedelaymsecEditField       matlab.ui.control.EditField
        SimulateButton               matlab.ui.control.Button
        UI_input_current             matlab.ui.control.UIAxes
        UI_Conductivities            matlab.ui.control.UIAxes
        UI_Currents                  matlab.ui.control.UIAxes
        UI_Vmembrane                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        current_amplitude; % Description
        current_duration;
        flag = false;
        time_delay;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AmplitudeuAcm2EditField
        function AmplitudeuAcm2EditFieldValueChanged(app, event)
            value = app.AmplitudeuAcm2EditField.Value;
            app.current_amplitude = value;
        end

        % Value changed function: DurationmsecEditField
        function DurationmsecEditFieldValueChanged(app, event)
            value = app.DurationmsecEditField.Value;
            app.current_duration = value;
        end

        % Value changed function: MorethanonestimulusCheckBox
        function MorethanonestimulusCheckBoxValueChanged(app, event)
            value = app.MorethanonestimulusCheckBox.Value;
            app.flag = true;
        end

        % Value changed function: TimedelaymsecEditField
        function TimedelaymsecEditFieldValueChanged(app, event)
            value = app.TimedelaymsecEditField.Value;
            app.time_delay = value;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            [outputDuration, current, outputMatrix, outputMatrix2] = my_simulate(app.current_amplitude, app.current_duration, app.flag, app.time_delay,0);
            plot(app.UI_input_current,outputDuration,current)
            app.UI_input_current.Visible = 'on';

            plot(app.UI_Conductivities,outputDuration(1:end-1),outputMatrix(2,:));
            hold(app.UI_Conductivities,'on');
            plot(app.UI_Conductivities,outputDuration(1:end-1),outputMatrix(3,:));
            hold(app.UI_Conductivities,'on');
            plot(app.UI_Conductivities,outputDuration(1:end-1),outputMatrix(4,:));
            legend(app.UI_Conductivities,'Sodium','Potassium','Leakage')
            app.UI_Conductivities.Visible = 'on';
            
            plot(app.UI_Currents,outputDuration,outputMatrix2(1,:));
            hold(app.UI_Currents,'on');
            plot(app.UI_Currents,outputDuration,outputMatrix2(2,:));
            hold(app.UI_Currents,'on');
            plot(app.UI_Currents,outputDuration,outputMatrix2(3,:));
            hold(app.UI_Currents,'on');
            plot(app.UI_Currents,outputDuration,outputMatrix2(4,:))
            legend(app.UI_Currents,'Sodium','Potassium','Leakage','Capacitive')
            app.UI_Currents.Visible = 'on';
            
            plot(app.UI_Vmembrane,outputDuration,outputMatrix2(5,:))
            app.UI_Vmembrane.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AmplitudeuAcm2Label = uilabel(app.UIFigure);
            app.AmplitudeuAcm2Label.Text = 'Amplitude (uA/cm^2)';
            app.AmplitudeuAcm2EditField = uieditfield(app.UIFigure, 'text');
            app.AmplitudeuAcm2EditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeuAcm2EditFieldValueChanged, true);
            app.DurationmsecEditFieldLabel = uilabel(app.UIFigure);
            app.DurationmsecEditFieldLabel.Text = 'Duration (msec)';
            app.DurationmsecEditField = uieditfield(app.UIFigure, 'text');
            app.DurationmsecEditField.ValueChangedFcn = createCallbackFcn(app, @DurationmsecEditFieldValueChanged, true);
            app.MorethanonestimulusCheckBox = uicheckbox(app.UIFigure);
            app.MorethanonestimulusCheckBox.ValueChangedFcn = createCallbackFcn(app, @MorethanonestimulusCheckBoxValueChanged, true);
            app.MorethanonestimulusCheckBox.Text = 'More than one stimulus';
            app.TimedelaymsecEditFieldLabel = uilabel(app.UIFigure);
            app.TimedelaymsecEditFieldLabel.Text = 'Time delay (msec)';
            app.TimedelaymsecEditField = uieditfield(app.UIFigure, 'text');
            app.TimedelaymsecEditField.ValueChangedFcn = createCallbackFcn(app, @TimedelaymsecEditFieldValueChanged, true);
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.UI_input_current = uiaxes(app.UIFigure);
            title(app.UI_input_current, 'Applied input current')
            xlabel(app.UI_input_current, 'Time (msec)')
            ylabel(app.UI_input_current, 'uA/cm^2')
            app.UI_input_current.Visible = 'off';
            app.UI_Conductivities = uiaxes(app.UIFigure);
            title(app.UI_Conductivities, 'Sodium, potassium, and leakage channel conductances')
            xlabel(app.UI_Conductivities, 'Time (msec)')
            ylabel(app.UI_Conductivities, 'mSm/cm^2')
            app.UI_Conductivities.Visible = 'off';
            app.UI_Currents = uiaxes(app.UIFigure);
            title(app.UI_Currents, 'Sodium, potassium,leakage and capacitive currents')
            xlabel(app.UI_Currents, 'Time (msec)')
            ylabel(app.UI_Currents, 'ua/cm^2')
            app.UI_Currents.Visible = 'off';
            app.UI_Vmembrane = uiaxes(app.UIFigure);
            title(app.UI_Vmembrane, 'Membrane Voltage')
            xlabel(app.UI_Vmembrane, 'Time (msec)')
            ylabel(app.UI_Vmembrane, 'mV')
            app.UI_Vmembrane.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_416

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end